classdef Prob_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SignalPanel                     matlab.ui.container.Panel
        CutoffFrequencyForHighPassFilter50HZ60HZEditField  matlab.ui.control.NumericEditField
        CutoffFrequencyForHighPassFilter50HZ60HZEditFieldLabel  matlab.ui.control.Label
        CutoffFrequencyForLowPassFilter05HZ06HZEditField  matlab.ui.control.NumericEditField
        CutoffFrequencyForLowPassFilter05HZ06HZEditFieldLabel  matlab.ui.control.Label
        ChooseTheSignalDropDown         matlab.ui.control.DropDown
        ChooseTheSignalDropDownLabel    matlab.ui.control.Label
        FilterAndProcessTheSignalButton  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        HRFromECGSignalvsSyntheticHRPanel  matlab.ui.container.Panel
        EditField_3                     matlab.ui.control.NumericEditField
        CorrelationCoefficientsEditField_2  matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        CorrelationCoefficientsEditField  matlab.ui.control.NumericEditField
        CorrelationCoefficientsEditFieldLabel  matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        SDOfSyntheticHREditField        matlab.ui.control.NumericEditField
        SDOfSyntheticHREditFieldLabel   matlab.ui.control.Label
        MeanOfSyntheticHREditField      matlab.ui.control.NumericEditField
        MeanOfSyntheticHREditFieldLabel  matlab.ui.control.Label
        SynthesizeAHeartRateTimeSequenceButton  matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        HRVAnalysisPanel                matlab.ui.container.Panel
        RootMeanSquareOfRRIntervalsEditField  matlab.ui.control.NumericEditField
        RootMeanSquareOfRRIntervalsEditFieldLabel  matlab.ui.control.Label
        StandardDeviationOfHeartRateEditField  matlab.ui.control.NumericEditField
        StandardDeviationOfHeartRateEditFieldLabel  matlab.ui.control.Label
        AverageHeartRateEditField       matlab.ui.control.NumericEditField
        AverageHeartRateEditFieldLabel  matlab.ui.control.Label
        DifferencebetweenMinandMaxIntervalsEditField  matlab.ui.control.NumericEditField
        DifferencebetweenMinandMaxIntervalsEditFieldLabel  matlab.ui.control.Label
        MaximumRRIntervalEditField      matlab.ui.control.NumericEditField
        MaximumRRIntervalEditFieldLabel  matlab.ui.control.Label
        MinimumRRIntervalEditField      matlab.ui.control.NumericEditField
        MinimumRRIntervalEditFieldLabel  matlab.ui.control.Label
        StandardDeviationofRRIntervalsEditField  matlab.ui.control.NumericEditField
        StandardDeviationofRRIntervalsEditFieldLabel  matlab.ui.control.Label
        AverageRRIntervalEditField      matlab.ui.control.NumericEditField
        AverageRRIntervalEditFieldLabel  matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        FrequencyDomainPanel            matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: ChooseTheSignalDropDown
        function ChooseTheSignalDropDownValueChanged(app, event)
            value = app.ChooseTheSignalDropDown.Value;
            switch value
                case "Signal 1"
                    T1 = readmatrix('T1.xls')
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "Signal 2"
                    T1 = readmatrix('T1.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "Signal 3"
                    T1 = readmatrix('T4.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "Signal 4"
                    T1 = readmatrix('T4.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "Signal 5"
                    T1 = readmatrix('T7.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "Signal 6"
                    T1 = readmatrix('T7.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                    plot(app.UIAxes, T1_s, T1_v1)
                case "---"
                    plot(app.UIAxes, [1], [1])

            end
        end

        % Button pushed function: FilterAndProcessTheSignalButton
        function FilterAndProcessTheSignalButtonPushed(app, event)
value = app.ChooseTheSignalDropDown.Value;
            switch value
                case "Signal 1"
                    T1 = readmatrix('T1.xls')
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                case "Signal 2"
                    T1 = readmatrix('T1.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                case "Signal 3"
                    T1 = readmatrix('T4.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                case "Signal 4"
                    T1 = readmatrix('T4.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                case "Signal 5"
                    T1 = readmatrix('T7.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 2)
                case "Signal 6"
                    T1 = readmatrix('T7.xls');
                    T1_s = T1(1:7680, 1)
                    T1_v1 = T1(1:7680, 3)
                case "---"
                    plot(app.UIAxes, [1], [1])
            end
Fcb = app.CutoffFrequencyForLowPassFilter05HZ06HZEditField.Value
Fcp = app.CutoffFrequencyForHighPassFilter50HZ60HZEditField.Value
Fs = 128
n = 7680

if (Fcb >= 0.5 && Fcb <= 0.6) && (Fcp >= 50 && Fcp <= 60) && (value ~= "---")
 
F1_v1 = fftshift(fft(T1_v1));
Fd_1 = Fs/n*(-n/2:n/2-1);

% Remove Baseline Drift and Powerline noise
FF1_v1 = F1_v1;
for i = [1:n]
    if ((Fd_1(i) <= Fcb && Fd_1(i) >= -Fcb) | (Fd_1(i) <= -Fcp | Fd_1(i) >= Fcp))
        FF1_v1(i) = 0;
    end
end


FT1_v1 = ifft(ifftshift(FF1_v1));
[pks,locs] = findpeaks(FT1_v1, Fs,'MinPeakDistance',0.29, 'MinPeakHeight', 0.1);
plot(app.UIAxes_2,T1_s, FT1_v1)
hold(app.UIAxes_2,"on")
stem(app.UIAxes_2,locs, pks)
hold(app.UIAxes_2,"off")


plot(app.UIAxes2,Fd_1, abs(F1_v1))
plot(app.UIAxes3,Fd_1, abs(FF1_v1))


% Find all R-R intervals and store them in an array
RR = [];
for i = 2:length(locs)
    RR(i-1) = locs(i)-locs(i-1);
end

% Find time for each RR interval
RR_s = cumsum(RR) + locs(1);

%claculations for RR interval
app.AverageRRIntervalEditField.Value = mean(RR)
app.StandardDeviationofRRIntervalsEditField.Value = std(RR)
min_RR = min(RR)
max_RR = max(RR)
app.MinimumRRIntervalEditField.Value = min_RR
app.MaximumRRIntervalEditField.Value = max_RR
app.DifferencebetweenMinandMaxIntervalsEditField.Value = max_RR-min_RR

%calculate heart rate
global Heart_Rate
Heart_Rate = 60./RR; %heart rate for each RR interval
app.AverageHeartRateEditField.Value = mean(Heart_Rate)
app.StandardDeviationOfHeartRateEditField.Value = std(Heart_Rate)

app.MeanOfSyntheticHREditField.Value = app.AverageHeartRateEditField.Value
app.SDOfSyntheticHREditField.Value = app.StandardDeviationOfHeartRateEditField.Value

%Time Domain HRV analysis
app.RootMeanSquareOfRRIntervalsEditField.Value = sqrt(mean(RR.^2))

%Frequency Domain HRV analysis
RR_f = fftshift(fft(RR));
RR_f(1) = 0;
RR_ps = (abs(RR_f).^2)/(length(RR_f).^2);
FsRR = length(RR)/RR_s(length(RR));
Fd_RR = FsRR/length(RR)*(-length(RR)/2:length(RR)/2-1);


plot(app.UIAxes4,RR_s, RR)

plot(app.UIAxes5,Fd_RR, abs(RR_f))

plot(app.UIAxes6,Fd_RR, abs(RR_ps))


app.EditField.Value = "Processing Was Successful!"

elseif ~((Fcb >= 0.5 && Fcb <= 0.6) && (Fcp >= 50 && Fcp <= 60)) && value ~= "---"
app.EditField.Value = "Please Enter The Specified Frequencies"

elseif value == "---"
app.EditField.Value = "Please Choose A Signal"
end
        end

        % Button pushed function: SynthesizeAHeartRateTimeSequenceButton
        function SynthesizeAHeartRateTimeSequenceButtonPushed(app, event)
global Heart_Rate
n = length(Heart_Rate);
c = [0.4, -0.4];
noise = app.SDOfSyntheticHREditField.Value*rand(n, 1);
synth_heart_rate = zeros(n, 1);
synth_heart_rate(1:2) = Heart_Rate(1:2);
for i = 3:n
    synth_heart_rate(i) = app.MeanOfSyntheticHREditField.Value + c*synth_heart_rate(i-2:i-1) + noise(i);
end

co = corrcoef(Heart_Rate, synth_heart_rate)
rmse = sqrt(sum(mean((Heart_Rate - synth_heart_rate).^2)))

app.RMSEEditField.Value = rmse
app.CorrelationCoefficientsEditField.Value = co(1,1)
app.CorrelationCoefficientsEditField_2.Value = co(1,2)
app.EditField_2.Value = co(2,1)
app.EditField_3.Value = co(2,2)

plot(app.UIAxes2_2,Heart_Rate)

plot(app.UIAxes3_2,synth_heart_rate)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.FrequencyDomainPanel = uipanel(app.UIFigure);
            app.FrequencyDomainPanel.Title = 'Frequency Domain';
            app.UIAxes2 = uiaxes(app.FrequencyDomainPanel);
            title(app.UIAxes2, 'ECG Signal (FD)')
            xlabel(app.UIAxes2, 'f')
            ylabel(app.UIAxes2, 'V(f)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.FrequencyDomainPanel);
            title(app.UIAxes3, 'Filtered ECG Signal (FD)')
            xlabel(app.UIAxes3, 'f')
            ylabel(app.UIAxes3, 'V(f)')
            zlabel(app.UIAxes3, 'Z')
            app.HRVAnalysisPanel = uipanel(app.UIFigure);
            app.HRVAnalysisPanel.Title = 'HRV Analysis';
            app.UIAxes4 = uiaxes(app.HRVAnalysisPanel);
            title(app.UIAxes4, 'RR intervals')
            xlabel(app.UIAxes4, 't')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.HRVAnalysisPanel);
            title(app.UIAxes5, 'RR in the frequency domain')
            xlabel(app.UIAxes5, 'f')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.HRVAnalysisPanel);
            title(app.UIAxes6, 'Power spectra of RR')
            xlabel(app.UIAxes6, 'f')
            zlabel(app.UIAxes6, 'Z')
            app.AverageRRIntervalEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.AverageRRIntervalEditFieldLabel.Text = 'Average RR Interval';
            app.AverageRRIntervalEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.AverageRRIntervalEditField.ValueDisplayFormat = '%5.4g';
            app.StandardDeviationofRRIntervalsEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.StandardDeviationofRRIntervalsEditFieldLabel.Text = 'Standard Deviation of RR Intervals';
            app.StandardDeviationofRRIntervalsEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.StandardDeviationofRRIntervalsEditField.ValueDisplayFormat = '%5.4g';
            app.MinimumRRIntervalEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.MinimumRRIntervalEditFieldLabel.Text = 'Minimum RR Interval';
            app.MinimumRRIntervalEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.MinimumRRIntervalEditField.ValueDisplayFormat = '%5.4g';
            app.MaximumRRIntervalEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.MaximumRRIntervalEditFieldLabel.Text = 'Maximum RR Interval';
            app.MaximumRRIntervalEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.MaximumRRIntervalEditField.ValueDisplayFormat = '%5.4g';
            app.DifferencebetweenMinandMaxIntervalsEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.DifferencebetweenMinandMaxIntervalsEditFieldLabel.Text = 'Difference between Min and Max Intervals';
            app.DifferencebetweenMinandMaxIntervalsEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.DifferencebetweenMinandMaxIntervalsEditField.ValueDisplayFormat = '%5.4g';
            app.AverageHeartRateEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.AverageHeartRateEditFieldLabel.Text = 'Average Heart Rate';
            app.AverageHeartRateEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.AverageHeartRateEditField.ValueDisplayFormat = '%5.4g';
            app.StandardDeviationOfHeartRateEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.StandardDeviationOfHeartRateEditFieldLabel.Text = 'Standard Deviation Of Heart Rate';
            app.StandardDeviationOfHeartRateEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.StandardDeviationOfHeartRateEditField.ValueDisplayFormat = '%5.4g';
            app.RootMeanSquareOfRRIntervalsEditFieldLabel = uilabel(app.HRVAnalysisPanel);
            app.RootMeanSquareOfRRIntervalsEditFieldLabel.Text = 'Root Mean Square Of  RR Intervals';
            app.RootMeanSquareOfRRIntervalsEditField = uieditfield(app.HRVAnalysisPanel, 'numeric');
            app.RootMeanSquareOfRRIntervalsEditField.ValueDisplayFormat = '%5.4g';
            app.HRFromECGSignalvsSyntheticHRPanel = uipanel(app.UIFigure);
            app.HRFromECGSignalvsSyntheticHRPanel.Title = 'HR From ECG Signal vs Synthetic HR';
            app.UIAxes2_2 = uiaxes(app.HRFromECGSignalvsSyntheticHRPanel);
            title(app.UIAxes2_2, 'Heart Rate From ECG Signal')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.HRFromECGSignalvsSyntheticHRPanel);
            title(app.UIAxes3_2, 'Synthetic Heart Rate')
            zlabel(app.UIAxes3_2, 'Z')
            app.SynthesizeAHeartRateTimeSequenceButton = uibutton(app.HRFromECGSignalvsSyntheticHRPanel, 'push');
            app.SynthesizeAHeartRateTimeSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeAHeartRateTimeSequenceButtonPushed, true);
            app.SynthesizeAHeartRateTimeSequenceButton.Text = 'Synthesize A Heart Rate Time Sequence';
            app.MeanOfSyntheticHREditFieldLabel = uilabel(app.HRFromECGSignalvsSyntheticHRPanel);
            app.MeanOfSyntheticHREditFieldLabel.Text = 'Mean Of Synthetic HR';
            app.MeanOfSyntheticHREditField = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.SDOfSyntheticHREditFieldLabel = uilabel(app.HRFromECGSignalvsSyntheticHRPanel);
            app.SDOfSyntheticHREditFieldLabel.Text = 'SD Of Synthetic HR';
            app.SDOfSyntheticHREditField = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.RMSEEditFieldLabel = uilabel(app.HRFromECGSignalvsSyntheticHRPanel);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.CorrelationCoefficientsEditFieldLabel = uilabel(app.HRFromECGSignalvsSyntheticHRPanel);
            app.CorrelationCoefficientsEditFieldLabel.Text = 'Correlation Coefficients';
            app.CorrelationCoefficientsEditField = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.EditField_2 = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.CorrelationCoefficientsEditField_2 = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.EditField_3 = uieditfield(app.HRFromECGSignalvsSyntheticHRPanel, 'numeric');
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.UIAxes_2 = uiaxes(app.SignalPanel);
            title(app.UIAxes_2, 'Filtered ECG Signal (TD)')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'V(t)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.SignalPanel);
            title(app.UIAxes, 'ECG Signal (TD)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'V(t)')
            zlabel(app.UIAxes, 'Z')
            app.EditField = uieditfield(app.SignalPanel, 'text');
            app.FilterAndProcessTheSignalButton = uibutton(app.SignalPanel, 'push');
            app.FilterAndProcessTheSignalButton.ButtonPushedFcn = createCallbackFcn(app, @FilterAndProcessTheSignalButtonPushed, true);
            app.FilterAndProcessTheSignalButton.Text = 'Filter And Process The Signal';
            app.ChooseTheSignalDropDownLabel = uilabel(app.SignalPanel);
            app.ChooseTheSignalDropDownLabel.Text = 'Choose The Signal';
            app.ChooseTheSignalDropDown = uidropdown(app.SignalPanel);
            app.ChooseTheSignalDropDown.Items = {'---', 'Signal 1', 'Signal 2', 'Signal 3', 'Signal 4', 'Signal 5', 'Signal 6'};
            app.ChooseTheSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseTheSignalDropDownValueChanged, true);
            app.ChooseTheSignalDropDown.Value = '---';
            app.CutoffFrequencyForLowPassFilter05HZ06HZEditFieldLabel = uilabel(app.SignalPanel);
            app.CutoffFrequencyForLowPassFilter05HZ06HZEditFieldLabel.Text = 'Cutoff Frequency For Low Pass Filter (0.5HZ-0.6HZ)';
            app.CutoffFrequencyForLowPassFilter05HZ06HZEditField = uieditfield(app.SignalPanel, 'numeric');
            app.CutoffFrequencyForLowPassFilter05HZ06HZEditField.Value = 0.5;
            app.CutoffFrequencyForHighPassFilter50HZ60HZEditFieldLabel = uilabel(app.SignalPanel);
            app.CutoffFrequencyForHighPassFilter50HZ60HZEditFieldLabel.Text = 'Cutoff Frequency For High Pass Filter (50HZ-60HZ)';
            app.CutoffFrequencyForHighPassFilter50HZ60HZEditField = uieditfield(app.SignalPanel, 'numeric');
            app.CutoffFrequencyForHighPassFilter50HZ60HZEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prob_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end